classdef testApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        Label           matlab.ui.control.Label
        EnterEditField  matlab.ui.control.NumericEditField
        EnterLabel      matlab.ui.control.Label
        PlotButton      matlab.ui.control.Button
        UIAxes2         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function PlotButtonPushed(app, event)
            
           % Load the MAT file
if exist('myData.mat', 'file')
    % Adjust variable names to match what's actually in your .mat file
    load('myData.mat', ...
         'SpecificPoints', 'VoltagePredVals', 'CurrentPredVals', ...
         'Va', 'Output10k3', 'xx');

    % For this example, we'll assume you want to see only testnum=1.
    % If you'd like to loop over multiple test numbers, just adapt the loop.
  
    testnum =  app.EnterEditField.Value 
    % -- Step 1: Extract Key Points --
    Isc   = SpecificPoints(testnum, 1);
    V_mpp = SpecificPoints(testnum, 2);
    I_mpp = SpecificPoints(testnum, 3);
    Voc   = SpecificPoints(testnum, 4);
    
    % -- Generate Spline/PCHIP Predictions --
    sampleyy_pred_spline = spline(sort(VoltagePredVals(testnum, :)), ...
                                  sort(CurrentPredVals(testnum, :), "descend"), xx);
    sampleyy_pred_pchip  = pchip(sort(VoltagePredVals(testnum, :)), ...
                                 sort(CurrentPredVals(testnum, :), "descend"), xx);

    % -- Clear axes and plot on App Designer UIAxes2 --
    cla(app.UIAxes2); 
    hold(app.UIAxes2, 'on');

    % Plot the original I-V curve (blue circles)
    plot(app.UIAxes2, Va, Output10k3(testnum, :), 'bo', 'MarkerFaceColor','b');

    % Plot the interpolated curves
    plot(app.UIAxes2, xx, sampleyy_pred_spline, 'r', 'LineWidth', 1);
    plot(app.UIAxes2, xx, sampleyy_pred_pchip, 'Color', [0 0.4 0], 'LineWidth', 1);

    % -- Customize the UIAxes --
    xlabel(app.UIAxes2, '$V_a$/V', 'FontSize', 14, 'Interpreter', 'latex');
    ylabel(app.UIAxes2, '$i$ (A/m$^2$)', 'FontSize', 14, 'Interpreter', 'latex');
    xlim(app.UIAxes2, [0, Voc]);
    ylim(app.UIAxes2, [0, Isc + 50]);
    grid(app.UIAxes2, 'on');

    hold(app.UIAxes2, 'off');
   
else
    % If the MAT-file is not found, alert the user
    uialert(app.UIFigure, 'MAT file not found!', 'Error', 'Icon', 'error');
end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed2(app, event)
            if exist('myData.mat', 'file')
    % Adjust variable names to match what's actually in your .mat file
    load('myData.mat', ...
         'SpecificPoints', 'VoltagePredVals', 'CurrentPredVals', ...
         'Va', 'Output10k3', 'xx');

    % For this example, we'll assume you want to see only testnum=1.
    % If you'd like to loop over multiple test numbers, just adapt the loop.
  
    testnum =  app.EnterEditField.Value 
    % -- Step 1: Extract Key Points --
    Isc   = SpecificPoints(testnum, 1);
    V_mpp = SpecificPoints(testnum, 2);
    I_mpp = SpecificPoints(testnum, 3);
    Voc   = SpecificPoints(testnum, 4);
    MPP = V_mpp*I_mpp;
    app.Label.Text = {['Isc: ', num2str(Isc), 'A, Voc:', ...
                           num2str(Voc), 'V, MPP: ', ...
                           num2str(V_mpp*I_mpp),'W']};
    
    % -- Generate Spline/PCHIP Predictions --
    sampleyy_pred_spline = spline(sort(VoltagePredVals(testnum, :)), ...
                                  sort(CurrentPredVals(testnum, :), "descend"), xx);
    sampleyy_pred_pchip  = pchip(sort(VoltagePredVals(testnum, :)), ...
                                 sort(CurrentPredVals(testnum, :), "descend"), xx);

    % -- Clear axes and plot on App Designer UIAxes2 --
    cla(app.UIAxes2); 
    hold(app.UIAxes2, 'on');

    % Plot the original I-V curve (blue circles)
    plot(app.UIAxes2, Va, Output10k3(testnum, :), 'bo', 'MarkerFaceColor','b');

    % Plot the interpolated curves
    plot(app.UIAxes2, xx, sampleyy_pred_spline, 'r', 'LineWidth', 1);
    plot(app.UIAxes2, xx, sampleyy_pred_pchip, 'Color', [0 0.4 0], 'LineWidth', 1);

    % -- Customize the UIAxes --
    xlabel(app.UIAxes2, '$V_a$/V', 'FontSize', 14, 'Interpreter', 'latex');
    ylabel(app.UIAxes2, '$i$ (A/m$^2$)', 'FontSize', 14, 'Interpreter', 'latex');
    xlim(app.UIAxes2, [0, Voc]);
    ylim(app.UIAxes2, [0, Isc + 50]);
    grid(app.UIAxes2, 'on');

    hold(app.UIAxes2, 'off');
   
else
    % If the MAT-file is not found, alert the user
    uialert(app.UIFigure, 'MAT file not found!', 'Error', 'Icon', 'error');
end

        end

        % Callback function: not associated with a component
        function DisplayValuesButtonPushed(app, event)
             app.TextArea.Value = {['Values: ', num2str(app.storedValues(1)), ', ', ...
                           num2str(app.storedValues(2)), ', ', ...
                           num2str(app.storedValues(3))]};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed2, true);
            app.PlotButton.Text = 'Plot';
            app.EnterLabel = uilabel(app.GridLayout);
            app.EnterLabel.Interpreter = 'latex';
            app.EnterLabel.Text = 'Enter :';
            app.EnterEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnterEditField.ValueDisplayFormat = '%12.4g';
            app.EnterEditField.Value = 1;
            app.Label = uilabel(app.GridLayout);
            app.Label.Interpreter = 'latex';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end